classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InputTab                    matlab.ui.container.Tab
        SensorsandactuatorsListBoxLabel  matlab.ui.control.Label
        SensorsandactuatorsListBox  matlab.ui.control.ListBox
        RunButton                   matlab.ui.control.StateButton
        SatelliteOrbitControlLabel  matlab.ui.control.Label
        AllpropertiesButtonGroup    matlab.ui.container.ButtonGroup
        DefaultButton               matlab.ui.control.RadioButton
        EditButton                  matlab.ui.control.RadioButton
        CubesatpropertiesPanel      matlab.ui.container.Panel
        Image2                      matlab.ui.control.Image
        MassUEditFieldLabel         matlab.ui.control.Label
        MassUEditField              matlab.ui.control.NumericEditField
        GravityCentermmLabel        matlab.ui.control.Label
        GC_user                     matlab.ui.control.EditField
        DynamicspropertiesPanel     matlab.ui.container.Panel
        AltitudekmLabel             matlab.ui.control.Label
        Altitude_user               matlab.ui.control.NumericEditField
        Image                       matlab.ui.control.Image
        InitialattituderadsLabel    matlab.ui.control.Label
        Initialattitude             matlab.ui.control.EditField
        SunCheckBox                 matlab.ui.control.CheckBox
        MoonCheckBox                matlab.ui.control.CheckBox
        DragCheckBox                matlab.ui.control.CheckBox
        TmagCheckBox                matlab.ui.control.CheckBox
        TggCheckBox                 matlab.ui.control.CheckBox
        PerturbationsLabel          matlab.ui.control.Label
        J2CheckBox                  matlab.ui.control.CheckBox
        ExitButton                  matlab.ui.control.Button
        OutputTab                   matlab.ui.container.Tab
        ExportalldatatoafileButton  matlab.ui.control.StateButton
        ExitButton_2                matlab.ui.control.Button
        threeDOrbitCheckBox         matlab.ui.control.CheckBox
        COECheckBox                 matlab.ui.control.CheckBox
        GroundtrackCheckBox         matlab.ui.control.CheckBox
        VisualizationLabel          matlab.ui.control.Label
        DynamicsCheckBox            matlab.ui.control.CheckBox
        SensordataCheckBox          matlab.ui.control.CheckBox
        NavigationCheckBox          matlab.ui.control.CheckBox
        AOCSCheckBox                matlab.ui.control.CheckBox
        FDIRCheckBox                matlab.ui.control.CheckBox
        ResultsLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            %Give resolution for your previous screen:
           
        end

        % Value changed function: MassUEditField
        function MassUEditFieldValueChanged(app, event)
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
           
            mass.user = app.MassUEditField.Value;
            assignin("base",'factormasa',mass.user);
            
             GC.user = app.GC_user.Value;
             GC.user2 = str2num(GC.user)
            assignin("base",'factorGC',GC.user2);
            
            
             altitude.user = app.Altitude_user.Value;
            assignin("base",'altitude',altitude.user);
            
             attitude.user = app.Initialattitude.Value;
             attitude.user2 = str2num(attitude.user)
            assignin("base",'attitude',attitude.user2);
            
            result.user = 'C:\Users\david\OneDrive\Escritorio\Gsoc\SmallSat_FDIR-master\Initialization.m';
            
            assignin("base",'result',result.user);
            
            evalin('base','Initialization');


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: ExitButton_2
        function ExitButton_2Pushed(app, event)
            closereq;
        end

        % Selection changed function: AllpropertiesButtonGroup
        function AllpropertiesButtonGroupSelectionChanged(app, event)
            selectedButton = app.AllpropertiesButtonGroup.SelectedObject;
            
        end

        % Value changed function: SunCheckBox
        function SunCheckBoxValueChanged(app, event)
            V1.user = app.SunCheckBox.Value;
            assignin("base",'Env_Sun',V1.user);
        end

        % Value changed function: MoonCheckBox
        function MoonCheckBoxValueChanged(app, event)
            V2.user = app.MoonCheckBox.Value;
            assignin("base",'Env_Moon',V2.user);
        end

        % Value changed function: DragCheckBox
        function DragCheckBoxValueChanged(app, event)
            V3.user = app.DragCheckBox.Value;
            assignin("base",'Env_Drag',V3.user);
        end

        % Value changed function: TmagCheckBox
        function TmagCheckBoxValueChanged(app, event)
            V4.user = app.TmagCheckBox.Value;
            assignin("base",'Env_TMag',V4.user);
        end

        % Value changed function: TggCheckBox
        function TggCheckBoxValueChanged(app, event)
            V5.user = app.TggCheckBox.Value;
            assignin("base",'Env_TGG',V5.user);
        end

        % Value changed function: J2CheckBox
        function J2CheckBoxValueChanged(app, event)
            V6.user = app.J2CheckBox.Value;
            assignin("base",'Env_J2',V6.user);
        end

        % Value changed function: threeDOrbitCheckBox
        function threeDOrbitCheckBoxValueChanged(app, event)
         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.SensorsandactuatorsListBoxLabel = uilabel(app.InputTab);
            app.SensorsandactuatorsListBoxLabel.Text = 'Sensors and actuators';
            app.SensorsandactuatorsListBox = uilistbox(app.InputTab);
            app.SensorsandactuatorsListBox.Items = {'Startracker', 'Thrusters', 'GPS', 'Gyroscope', 'Magnetorquer', 'Reaction wheels'};
            app.SensorsandactuatorsListBox.Value = 'Startracker';
            app.RunButton = uibutton(app.InputTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.SatelliteOrbitControlLabel = uilabel(app.InputTab);
            app.SatelliteOrbitControlLabel.Text = 'Satellite Orbit Control';
            app.AllpropertiesButtonGroup = uibuttongroup(app.InputTab);
            app.AllpropertiesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AllpropertiesButtonGroupSelectionChanged, true);
            app.AllpropertiesButtonGroup.Title = 'All properties';
            app.DefaultButton = uiradiobutton(app.AllpropertiesButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.EditButton = uiradiobutton(app.AllpropertiesButtonGroup);
            app.EditButton.Text = 'Edit';
            app.CubesatpropertiesPanel = uipanel(app.InputTab);
            app.CubesatpropertiesPanel.Title = '                                                                     Cubesat properties';
            app.Image2 = uiimage(app.CubesatpropertiesPanel);
            app.Image2.ImageSource = 'sattt.PNG';
            app.MassUEditFieldLabel = uilabel(app.CubesatpropertiesPanel);
            app.MassUEditFieldLabel.Text = 'Mass (U)';
            app.MassUEditField = uieditfield(app.CubesatpropertiesPanel, 'numeric');
            app.MassUEditField.ValueChangedFcn = createCallbackFcn(app, @MassUEditFieldValueChanged, true);
            app.MassUEditField.Value = 15;
            app.GravityCentermmLabel = uilabel(app.CubesatpropertiesPanel);
            app.GravityCentermmLabel.Text = 'GravityCenter [mm]';
            app.GC_user = uieditfield(app.CubesatpropertiesPanel, 'text');
            app.DynamicspropertiesPanel = uipanel(app.InputTab);
            app.DynamicspropertiesPanel.Title = '                                                                     Dynamics properties';
            app.AltitudekmLabel = uilabel(app.DynamicspropertiesPanel);
            app.AltitudekmLabel.Text = 'Altitude(km) ';
            app.Altitude_user = uieditfield(app.DynamicspropertiesPanel, 'numeric');
            app.Image = uiimage(app.DynamicspropertiesPanel);
            app.Image.ImageSource = 'Captura.PNG';
            app.InitialattituderadsLabel = uilabel(app.DynamicspropertiesPanel);
            app.InitialattituderadsLabel.Text = 'Initial attitude [rad/s]';
            app.Initialattitude = uieditfield(app.DynamicspropertiesPanel, 'text');
            app.SunCheckBox = uicheckbox(app.DynamicspropertiesPanel);
            app.SunCheckBox.ValueChangedFcn = createCallbackFcn(app, @SunCheckBoxValueChanged, true);
            app.SunCheckBox.Text = 'Sun';
            app.MoonCheckBox = uicheckbox(app.DynamicspropertiesPanel);
            app.MoonCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoonCheckBoxValueChanged, true);
            app.MoonCheckBox.Text = 'Moon';
            app.DragCheckBox = uicheckbox(app.DynamicspropertiesPanel);
            app.DragCheckBox.ValueChangedFcn = createCallbackFcn(app, @DragCheckBoxValueChanged, true);
            app.DragCheckBox.Text = 'Drag';
            app.TmagCheckBox = uicheckbox(app.DynamicspropertiesPanel);
            app.TmagCheckBox.ValueChangedFcn = createCallbackFcn(app, @TmagCheckBoxValueChanged, true);
            app.TmagCheckBox.Text = 'Tmag';
            app.TggCheckBox = uicheckbox(app.DynamicspropertiesPanel);
            app.TggCheckBox.ValueChangedFcn = createCallbackFcn(app, @TggCheckBoxValueChanged, true);
            app.TggCheckBox.Text = 'Tgg';
            app.PerturbationsLabel = uilabel(app.DynamicspropertiesPanel);
            app.PerturbationsLabel.Text = 'Perturbations:';
            app.J2CheckBox = uicheckbox(app.DynamicspropertiesPanel);
            app.J2CheckBox.ValueChangedFcn = createCallbackFcn(app, @J2CheckBoxValueChanged, true);
            app.J2CheckBox.Text = 'J2';
            app.ExitButton = uibutton(app.InputTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.ExportalldatatoafileButton = uibutton(app.OutputTab, 'state');
            app.ExportalldatatoafileButton.Text = 'Export all data to a file';
            app.ExitButton_2 = uibutton(app.OutputTab, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_2Pushed, true);
            app.ExitButton_2.Text = 'Exit';
            app.threeDOrbitCheckBox = uicheckbox(app.OutputTab);
            app.threeDOrbitCheckBox.ValueChangedFcn = createCallbackFcn(app, @threeDOrbitCheckBoxValueChanged, true);
            app.threeDOrbitCheckBox.Text = '3D Orbit';
            app.COECheckBox = uicheckbox(app.OutputTab);
            app.COECheckBox.Text = 'COE';
            app.GroundtrackCheckBox = uicheckbox(app.OutputTab);
            app.GroundtrackCheckBox.Text = 'Groundtrack';
            app.VisualizationLabel = uilabel(app.OutputTab);
            app.VisualizationLabel.Text = 'Visualization';
            app.DynamicsCheckBox = uicheckbox(app.OutputTab);
            app.DynamicsCheckBox.Text = 'Dynamics';
            app.SensordataCheckBox = uicheckbox(app.OutputTab);
            app.SensordataCheckBox.Text = 'Sensor data';
            app.NavigationCheckBox = uicheckbox(app.OutputTab);
            app.NavigationCheckBox.Text = 'Navigation';
            app.AOCSCheckBox = uicheckbox(app.OutputTab);
            app.AOCSCheckBox.Text = 'AOCS';
            app.FDIRCheckBox = uicheckbox(app.OutputTab);
            app.FDIRCheckBox.Text = 'FDIR';
            app.ResultsLabel = uilabel(app.OutputTab);
            app.ResultsLabel.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end